classdef app_mini_projet < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        CHOISIRButton                   matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
        NEGATIFButton                   matlab.ui.control.Button
        EDGEButton                      matlab.ui.control.Button
        CONVERSIONENNIVEAUDEGRISButton  matlab.ui.control.Button
        FILTREGAUSSIENButton            matlab.ui.control.Button
        MASQUAGEDEZONEButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        img % Description
        img_output % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CHOISIRButton
        function CHOISIR(app, event)
            [file, path] = uigetfile('*. *');
            if isequal (file,0)   % pour que si l'utilisateur cancel l'operation
                figure(app.UIFigure);
                return;
            end
            figure(app.UIFigure);
            app.img=imread(fullfile(path,file));
            imshow(app.img,'parent',app.UIAxes);
        end

        % Callback function
        function HISTOGRAMME(app, event)
            
        end

        % Button pushed function: NEGATIFButton
        function negatif(app, event)
            app.img_output = imcomplement(app.img);
            imshow(app.img_output,'parent',app.UIAxes2);
        end

        % Button pushed function: EDGEButton
        function edge(app, event)
            app.img_output= edge(app.img, 'Sobel');
             imshow(app.img_output,'parent',app.UIAxes2);
        end

        % Button pushed function: CONVERSIONENNIVEAUDEGRISButton
        function conversion_gris(app, event)
            app.img_output=rgb2gray(app.img);
            imshow(app.img_output,'parent',app.UIAxes2);
        end

        % Button pushed function: FILTREGAUSSIENButton
        function gauss(app, event)
            sigma = 2; 
            hsize = 7;
            h = fspecial('gaussian', hsize, sigma);
            app.img_output = imfilter(app.img, h);
            imshow(app.img_output,'parent',app.UIAxes2);
            
        end

        % Button pushed function: MASQUAGEDEZONEButton
        function MASQ_ZONE(app, event)
            mask = (app.img >= 0 & app.img <= 15) | (app.img >= 240 & app.img <= 255);
            J = app.img;
            J(mask) = 128;
            imshow(J,'parent',app.UIAxes2);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'input')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.CHOISIRButton = uibutton(app.GridLayout, 'push');
            app.CHOISIRButton.ButtonPushedFcn = createCallbackFcn(app, @CHOISIR, true);
            app.CHOISIRButton.Text = 'CHOISIR';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'output')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.NEGATIFButton = uibutton(app.GridLayout, 'push');
            app.NEGATIFButton.ButtonPushedFcn = createCallbackFcn(app, @negatif, true);
            app.NEGATIFButton.Text = 'NEGATIF';
            app.EDGEButton = uibutton(app.GridLayout, 'push');
            app.EDGEButton.ButtonPushedFcn = createCallbackFcn(app, @edge, true);
            app.EDGEButton.Text = 'EDGE';
            app.CONVERSIONENNIVEAUDEGRISButton = uibutton(app.GridLayout, 'push');
            app.CONVERSIONENNIVEAUDEGRISButton.ButtonPushedFcn = createCallbackFcn(app, @conversion_gris, true);
            app.CONVERSIONENNIVEAUDEGRISButton.Text = 'CONVERSION EN NIVEAU DE GRIS';
            app.FILTREGAUSSIENButton = uibutton(app.GridLayout, 'push');
            app.FILTREGAUSSIENButton.ButtonPushedFcn = createCallbackFcn(app, @gauss, true);
            app.FILTREGAUSSIENButton.Text = 'FILTRE GAUSSIEN';
            app.MASQUAGEDEZONEButton = uibutton(app.GridLayout, 'push');
            app.MASQUAGEDEZONEButton.ButtonPushedFcn = createCallbackFcn(app, @MASQ_ZONE, true);
            app.MASQUAGEDEZONEButton.Text = 'MASQUAGE DE ZONE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_mini_projet

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end